classdef PM20383201 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SimulationResolutionEditField   matlab.ui.control.NumericEditField
        SimulationResolutionLabel       matlab.ui.control.Label
        ExportSimulationButton          matlab.ui.control.Button
        VectorEditField                 matlab.ui.control.EditField
        VectorEditFieldLabel            matlab.ui.control.Label
        ComputevectorfromtableButton    matlab.ui.control.Button
        TextArea                        matlab.ui.control.TextArea
        VerticalDistanceEditField       matlab.ui.control.EditField
        VerticalDistanceEditFieldLabel  matlab.ui.control.Label
        TotaltimeEditField              matlab.ui.control.EditField
        TotaltimeEditFieldLabel         matlab.ui.control.Label
        VelocityEditField               matlab.ui.control.EditField
        VelocityEditFieldLabel          matlab.ui.control.Label
        TimeEditField                   matlab.ui.control.EditField
        TimeEditFieldLabel              matlab.ui.control.Label
        HorizontalDistanceEditField     matlab.ui.control.EditField
        HorizontalDistanceEditFieldLabel  matlab.ui.control.Label
        RemoveButton                    matlab.ui.control.Button
        NoEditField                     matlab.ui.control.NumericEditField
        NoEditFieldLabel                matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        DistancefromWallEditField       matlab.ui.control.NumericEditField
        DistancefromWallEditFieldLabel  matlab.ui.control.Label
        SimulateButton                  matlab.ui.control.Button
        MarkerSlider                    matlab.ui.control.Slider
        MarkerSliderLabel               matlab.ui.control.Label
        AngleEditField                  matlab.ui.control.NumericEditField
        AngleEditFieldLabel             matlab.ui.control.Label
        SpeedEditField                  matlab.ui.control.NumericEditField
        SpeedEditFieldLabel             matlab.ui.control.Label
        AddButton                       matlab.ui.control.Button
        CheckBox                        matlab.ui.control.CheckBox
        WallHeightEditField             matlab.ui.control.NumericEditField
        WallHeightEditFieldLabel        matlab.ui.control.Label
        SimulationofaprojectileinmotionLabel  matlab.ui.control.Label
        HeightofprojectileEditField     matlab.ui.control.NumericEditField
        HeightofprojectileEditFieldLabel  matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private) %sets the properties tack to public so that the internal code can access the variables
        proXValues % projectile x values
        proYValues % projectile y values
        proTimeAtXValues % projectile time at x values
        marker % marker value
        xComp % x component
        yComp % y component
        angle % angle value
        magArrow % magnitude arrow plot
        finalVelocityX % final velocity of x
        finalVelocityY % final velocity of y
        velocityAtAnyGivenTime % veclocity at a given time
        angleAtAnyGivenTime % angle at a given time
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            if app.CheckBox.Value == 1 %if the wall check box is ticked
                app.WallHeightEditField.Enable = 1; %enable the wall height edit field
                app.DistancefromWallEditField.Enable = 1; %enable the wall distance edit field
            else %if not the if condition
                app.WallHeightEditField.Enable = 0; %disable the wall height edit field
                app.DistancefromWallEditField.Enable = 0; %disable the wall distance edit field
            end %end the if condition
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            tableOld = get(app.UITable,'Data'); %sets tableOld to the current data of the UI.Table element
            if abs(app.AngleEditField.Value) > 90 %if condition for angle larger than 90 degrees
                msgbox('Please Enter angle less than 90 Degrees', 'Error','error'); %error messasge box
            else %if not the if condition
                tableNew = [tableOld; height(tableOld)+1, abs(app.SpeedEditField.Value), abs(app.AngleEditField.Value)]; %app the new vector to the newtable
                app.TextArea.Value = sprintf("Added Speed: %.1f and Angle: %.1f",abs(app.SpeedEditField.Value),abs(app.AngleEditField.Value)); %disp the string in the error field
                set(app.UITable,'Data', (tableNew)); %set the new data to the table
            end %terminate the if statment
        end

        % Value changed function: CheckBox
        function CheckBoxValueChanged(app, event)
            value = app.CheckBox.Value; %set value to 1 if ticked and 0 if not
            if value == 1 %if checked
                app.WallHeightEditField.Enable = 1; %enable the wall height edit field
                app.DistancefromWallEditField.Enable = 1; %enable the wall distance edit field
            else
                app.WallHeightEditField.Enable = 0; %disable the wall height edit field
                app.DistancefromWallEditField.Enable = 0; %disable the wall distance edit field
            end %terminate the if statment
        end

        % Button pushed function: RemoveButton
        function RemoveButtonPushed(app, event)
            table = get(app.UITable, 'Data'); %set table to the data in the table
            removeItem  = app.NoEditField.Value; %get the item index to be removed
            if isempty(table) %if the table is empty
                msgbox("No values in table to remove", 'Error','error'); %error message box
            elseif removeItem == 0 %if the index is 0
                msgbox("Please enter Num of item to remove", 'Error','error'); %error message box
            elseif removeItem > table(end,1) %if the index is larger than the length of the table data
                msgbox("Num entered out of range", 'Error','error'); %error message box
            else
                table(removeItem,:) = []; %set the row of the index to empty
                app.TextArea.Value = sprintf("Removed No. %d from the table",removeItem); %set the string to the message box area
                for ii = 1:length(table(:,1)) %for loop from 1 to the length of the first column
                    if table(ii,1) > removeItem %if the index of ii is larger than the remove item index
                        table(ii,1) = table(ii,1) - 1; %reduce the number of the row by one
                    end %terminate the if statment
                end %terminate the for loop
                set(app.UITable, 'Data', (table)); %update the table to the new data
            end %terminate the if statment
        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            cla(app.UIAxes, "reset"); %clear the current axes
            hold(app.UIAxes,'on'); %allow multiple plots on the same graph
            axis(app.UIAxes, 'padded'); %set the axes to padded
            resolution = app.SimulationResolutionEditField.Value; %get the resolution edit feild value
            
            if app.CheckBox.Value == 1 %if the checkbox is checked
                wallHeight = abs(app.WallHeightEditField.Value); %set wall height to the absolute of the wall height field
                wallDistance = abs(app.DistancefromWallEditField.Value); %set the wall distance to the absolute of the wall distance field
            else %if not checked
                wallHeight = 0; %set wall height to 0
                wallDistance = 0; %set the wall distance to 0
            end %terminate the if statment
            if isempty(app.VectorEditField.Value) || isempty(get(app.UITable, 'Data')) %if the vector edit field is empty or the table is empty
                msgbox("No Vector to simulate", 'Error','error'); %error message box
            else %if not empty
                components = strsplit(app.VectorEditField.Value, ","); %set components to the string split of the vector edit field
                app.xComp = str2double(components(1)); %set the xComp to the double value of the 1st component of component
                app.yComp = str2double(components(2)); %set the yComp to the double value of the 2nd component of component
                proHeight = abs(app.HeightofprojectileEditField.Value); %set proHeight to the absolute of the projectile heild edit field
                proTimeAtMaxDistance1 = ((-1*app.yComp)+((app.yComp^2)-4*(-4.9*proHeight))^0.5)/(2*(-4.9)); %solve of the first time using the modified quadratic formula
                proTimeAtMaxDistance2 = ((-1*app.yComp)-((app.yComp^2)-4*(-4.9*proHeight))^0.5)/(2*(-4.9)); %soilve the second time using the modified quadratic formula
                if proTimeAtMaxDistance2 > proTimeAtMaxDistance1 %if the proTime..2 is larger than proTime..1
                    proTimeAtMaxDistance = proTimeAtMaxDistance2; %proTime.. is equal to proTime..2
                else %if not condition
                    proTimeAtMaxDistance = proTimeAtMaxDistance1; %proTime.. is equal to proTime..1
                end %terminate the if condition
                proMaxDistance = app.xComp*proTimeAtMaxDistance; %set proMaxDistacne to the product of the Xcomp and proTimeAtMaxDistance
                app.proTimeAtXValues = linspace(0,proTimeAtMaxDistance,resolution); %create vecotr of the length of resolution from 0 to proTimeAtMaxDistance
                app.proXValues = linspace(0,proMaxDistance,resolution); %create vecotr of the length of resolution from 0 to proMaxDistance
                app.proYValues = app.yComp.*app.proTimeAtXValues - 4.9*(app.proTimeAtXValues.^2)+proHeight; %calulate the y values from equation and x values
                if app.CheckBox.Value == 1 %if the wall checkbox is checked
                    line(app.UIAxes, [wallDistance wallDistance], [0,wallHeight]); %plot line from origin to wall height and wall distance
                    for ii = 1:length(app.proXValues) %for loop from 1 to length of proXValues
                        if ii > 1 && app.proYValues(ii-1) > wallHeight && app.proXValues(ii) > wallDistance %if ii is larger than 1 and ii-1 values of proYValues is less than wall height and ii proXValues is larger than wallDistance
                            break %break the for loop
                        end %terminate the if statment
                        if app.proYValues(ii) < wallHeight && app.proXValues(ii) > wallDistance %if statment that yValues ii are less than wallHeight and proXValues ii are larger than wallDistance
                            app.proYValues(ii:end) = []; %set item to empty
                            app.proXValues(ii:end) = []; %set item to empty
                            app.proTimeAtXValues(ii:end) = []; %set item to empty
                            break %break the for loop
                        end %terminate the if statment
                    end %termiante the for loop
                end %termiante the if statment
                yline(app.UIAxes, 0); %create line at y=0
                xline(app.UIAxes, 0); %create line at x=0
                plot(app.UIAxes,app.proXValues,app.proYValues, "Color",'b'); %plot the projetile trajectory
                for ii = 1:resolution/10:length(app.proXValues) %for loop from 1 to length of proXValues with step of resolution/10
                    proAnim = plot(app.UIAxes,app.proXValues(ii), app.proYValues(ii), 'ok', "MarkerFaceColor",'k', "MarkerSize",5); %plot the animation marker
                    drawnow; %draw the marker
                    pause(0.1); %pause the for loop for 0.1 second
                    delete(proAnim); %delete the 
                end %terminate the for loop
                app.ExportSimulationButton.Enable = 1; %enable GUI element
                app.VelocityEditField.Enable = 1; %enable GUI element
                app.TimeEditField.Enable = 1; %enable GUI element
                app.HorizontalDistanceEditField.Enable = 1; %enable GUI element
                app.VerticalDistanceEditField.Enable = 1; %enable GUI element
                app.MarkerSlider.Enable = 1; %enable GUI element
                app.TotaltimeEditField.Value = sprintf("%.3f", app.proTimeAtXValues(end)); %set the totaltimeEditField to string
                app.MarkerSlider.Limits(1) = 1; %set the slider lower limit to 1
                lengthOfXValues = length(app.proXValues); %set length of x values to the length of proXValues
                app.MarkerSlider.Limits(2) = lengthOfXValues; %set the upper limit to lengthOfXValues 
                app.MarkerSlider.Value = lengthOfXValues; %set the slider marker to LengthOfXValues
                app.marker = plot(app.UIAxes,app.proXValues(end), app.proYValues(end), 'ob', 'MarkerFaceColor','b'); %plot the marker with set x and y values with options set
                app.TimeEditField.Value = sprintf("%.3f seconds",app.proTimeAtXValues(lengthOfXValues)); %set the timeEditField to the string set
                app.HorizontalDistanceEditField.Value = sprintf("%.3f meters",app.proXValues(lengthOfXValues)); %set horizontalDistance to string
                app.VerticalDistanceEditField.Value = sprintf("%.3f meters", app.proYValues(lengthOfXValues)); %set verticalDistance to string
                app.angle = atand(app.yComp/app.xComp); %saet angle to equation
                app.VelocityEditField.Value = sprintf("0, 0"); %set velocityEditField to 0, 0
                initialVelocity = sqrt(app.xComp^2+app.yComp^2); %set the initialVelocity to equation (trig)
                app.finalVelocityX = initialVelocity*cosd(app.angle); %find x component of final velocity
                app.finalVelocityY = initialVelocity*sind(app.angle) + (-9.8.*app.proTimeAtXValues); %find y component of final velocity
                app.velocityAtAnyGivenTime = sqrt(app.finalVelocityX^2+ app.finalVelocityY.^2); %set velocityAtAnyGivenAngle to trig equaion of final velocity components
                app.angleAtAnyGivenTime = atand(app.finalVelocityY/app.finalVelocityX); %find angle at any given time using tan(O/A)
                x = app.proXValues(lengthOfXValues); %set x to lengthOfXValues index of proXvalues
                y = app.proYValues(lengthOfXValues); %set y to lengthOfYValues index of proYvalues
                multiplier = max(app.velocityAtAnyGivenTime)/200; %set multiplier to the largest values of velocityAtAnyTime.. /200
                L = app.velocityAtAnyGivenTime(lengthOfXValues)*multiplier; %apply multiplier to product of velocityAtAnyGivenTime
                Theta = app.angleAtAnyGivenTime(lengthOfXValues); %set Theta to last value of angleAtAnyGivenTime
                app.magArrow = line(app.UIAxes, [x (x+(L*cosd(Theta)))], [y (y+(L*sind(Theta)))], 'Color', 'r', 'Linewidth', 2); %plot magnitude line of point at x(max),y(max)
            end %terminate if statment
        end

        % Button pushed function: ComputevectorfromtableButton
        function ComputevectorfromtableButtonPushed(app, event)
            table = get(app.UITable, 'Data'); %set table to data in UI.Table
            if isempty(table) %if table is empty
                msgbox("Please add values to table", 'Error','error'); %error message box
            else %if not empty
                app.xComp = 0; %set xComp to 0
                app.yComp = 0; %set zComp to 0
                for ii = 1:length(table(:,1)) %for loop from 1 to length of table first colunm
                    app.xComp = app.xComp + (table(ii,2)*cosd(table(ii,3))); %add value to xComp
                    app.yComp = app.yComp + (table(ii,2)*sind(table(ii,3))); %add value to yComp
                end %terminate for loop
                app.VectorEditField.Value = sprintf("%.2f, %.2f", app.xComp, app.yComp); %set VectorEditField to string
            end %termiante the if statment
        end

        % Value changed function: MarkerSlider
        function MarkerSliderValueChanged(app, event)
            markerPosition = ceil(app.MarkerSlider.Value); %set markerPosition to current value of slider
            delete(app.marker); %delete the marker plot
            delete(app.magArrow); %delete the magarrow plot
            app.marker = plot(app.UIAxes,app.proXValues(markerPosition), app.proYValues(markerPosition), 'ob', 'MarkerFaceColor','b'); %plot marker with current slider index of values 
            app.TimeEditField.Value = sprintf("%.3f seconds",app.proTimeAtXValues(markerPosition)); %set the timeEditField to the string set
            app.HorizontalDistanceEditField.Value = sprintf("%.3f meters", app.proXValues(markerPosition)); %set horizontalDistance to string
            app.VerticalDistanceEditField.Value = sprintf("%.3f meters", app.proYValues(markerPosition)); %set verticalDistance to string
            app.VelocityEditField.Value = sprintf("%.1f m/s, %.1f degrees", app.velocityAtAnyGivenTime(markerPosition),app.angleAtAnyGivenTime(markerPosition)); %set VelocityEditField to string given values
            x = app.proXValues(markerPosition); %set x to lengthOfXValues index of proXvalues
            y = app.proYValues(markerPosition); %set y to lengthOfYValues index of proYvalues
            multiplier = max(app.velocityAtAnyGivenTime)/200; %set multiplier to the largest values of velocityAtAnyTime.. /200
            LengthOfVector = app.velocityAtAnyGivenTime(markerPosition)*multiplier; %apply multiplier to product of velocityAtAnyGivenTime
            Theta = app.angleAtAnyGivenTime(markerPosition); %set Theta to last value of angleAtAnyGivenTime
            x2 = x + (LengthOfVector*cosd(Theta)); %calc x2 value from equation
            y2 = y + (LengthOfVector*sind(Theta)); %calc y2 value from equation
            app.magArrow = line(app.UIAxes, [x x2], [y y2], 'Color', 'r', 'Linewidth', 2); %plot line to shopw magnitude and direction of projectile
        end

        % Button pushed function: ExportSimulationButton
        function ExportSimulationButtonPushed(app, event)
            [file,path] = uiputfile("Figure.png"); %open file saveapi and set filename and path to respective names
            exportapp(app.UIFigure, fullfile(path, file)); %export the GUI to the location and filename set by user
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Projectile Simulation')
            xlabel(app.UIAxes, 'Distance')
            ylabel(app.UIAxes, 'Height')
            zlabel(app.UIAxes, 'Z')
            app.HeightofprojectileEditFieldLabel = uilabel(app.UIFigure);
            app.HeightofprojectileEditFieldLabel.Text = 'Height of projectile';
            app.HeightofprojectileEditField = uieditfield(app.UIFigure, 'numeric');
            app.HeightofprojectileEditField.Tooltip = {'Starting Height of the projectile in m.'};
            app.SimulationofaprojectileinmotionLabel = uilabel(app.UIFigure);
            app.SimulationofaprojectileinmotionLabel.Text = 'Simulation of a projectile in motion';
            app.WallHeightEditFieldLabel = uilabel(app.UIFigure);
            app.WallHeightEditFieldLabel.Text = 'Wall Height';
            app.WallHeightEditField = uieditfield(app.UIFigure, 'numeric');
            app.WallHeightEditField.Tooltip = {'Height of the wall in m.'};
            app.CheckBox = uicheckbox(app.UIFigure);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox.Tooltip = {'Enable Wall.'};
            app.CheckBox.Text = '';
            app.AddButton = uibutton(app.UIFigure, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = {'Add'; ''};
            app.SpeedEditFieldLabel = uilabel(app.UIFigure);
            app.SpeedEditFieldLabel.Text = 'Speed';
            app.SpeedEditField = uieditfield(app.UIFigure, 'numeric');
            app.SpeedEditField.Tooltip = {'Speed of projectile in m/s.'};
            app.AngleEditFieldLabel = uilabel(app.UIFigure);
            app.AngleEditFieldLabel.Text = 'Angle';
            app.AngleEditField = uieditfield(app.UIFigure, 'numeric');
            app.AngleEditField.Tooltip = {'angle at which object will be projected in dregrees.'};
            app.MarkerSliderLabel = uilabel(app.UIFigure);
            app.MarkerSliderLabel.Enable = 'off';
            app.MarkerSliderLabel.Text = 'Marker';
            app.MarkerSlider = uislider(app.UIFigure);
            app.MarkerSlider.ValueChangedFcn = createCallbackFcn(app, @MarkerSliderValueChanged, true);
            app.MarkerSlider.Tag = 'Slider';
            app.MarkerSlider.Enable = 'off';
            app.SimulateButton = uibutton(app.UIFigure, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Tooltip = {'Simulate Projectile.'};
            app.SimulateButton.Text = 'Simulate';
            app.DistancefromWallEditFieldLabel = uilabel(app.UIFigure);
            app.DistancefromWallEditFieldLabel.Text = 'Distance from Wall';
            app.DistancefromWallEditField = uieditfield(app.UIFigure, 'numeric');
            app.DistancefromWallEditField.Tooltip = {'Distance from origin for the wall in m.'};
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'No.'; 'Speed'; 'Angel'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [true true true];
            app.NoEditFieldLabel = uilabel(app.UIFigure);
            app.NoEditFieldLabel.Text = 'No.';
            app.NoEditField = uieditfield(app.UIFigure, 'numeric');
            app.NoEditField.Tooltip = {'Number of item to remove.'};
            app.RemoveButton = uibutton(app.UIFigure, 'push');
            app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveButtonPushed, true);
            app.RemoveButton.Text = 'Remove';
            app.HorizontalDistanceEditFieldLabel = uilabel(app.UIFigure);
            app.HorizontalDistanceEditFieldLabel.Enable = 'off';
            app.HorizontalDistanceEditFieldLabel.Text = 'Horizontal Distance';
            app.HorizontalDistanceEditField = uieditfield(app.UIFigure, 'text');
            app.HorizontalDistanceEditField.Editable = 'off';
            app.HorizontalDistanceEditField.Enable = 'off';
            app.TimeEditFieldLabel = uilabel(app.UIFigure);
            app.TimeEditFieldLabel.Enable = 'off';
            app.TimeEditFieldLabel.Text = 'Time';
            app.TimeEditField = uieditfield(app.UIFigure, 'text');
            app.TimeEditField.Editable = 'off';
            app.TimeEditField.Enable = 'off';
            app.VelocityEditFieldLabel = uilabel(app.UIFigure);
            app.VelocityEditFieldLabel.Enable = 'off';
            app.VelocityEditFieldLabel.Text = 'Velocity';
            app.VelocityEditField = uieditfield(app.UIFigure, 'text');
            app.VelocityEditField.Editable = 'off';
            app.VelocityEditField.Enable = 'off';
            app.TotaltimeEditFieldLabel = uilabel(app.UIFigure);
            app.TotaltimeEditFieldLabel.Text = 'Total time';
            app.TotaltimeEditField = uieditfield(app.UIFigure, 'text');
            app.TotaltimeEditField.Editable = 'off';
            app.TotaltimeEditField.Tooltip = {'total time taken for projectile to come to rest in seconds.'};
            app.VerticalDistanceEditFieldLabel = uilabel(app.UIFigure);
            app.VerticalDistanceEditFieldLabel.Enable = 'off';
            app.VerticalDistanceEditFieldLabel.Text = 'Vertical Distance';
            app.VerticalDistanceEditField = uieditfield(app.UIFigure, 'text');
            app.VerticalDistanceEditField.Editable = 'off';
            app.VerticalDistanceEditField.Enable = 'off';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            app.ComputevectorfromtableButton = uibutton(app.UIFigure, 'push');
            app.ComputevectorfromtableButton.ButtonPushedFcn = createCallbackFcn(app, @ComputevectorfromtableButtonPushed, true);
            app.ComputevectorfromtableButton.Tooltip = {'Calculate the resultant vector.'};
            app.ComputevectorfromtableButton.Text = 'Compute vector from table';
            app.VectorEditFieldLabel = uilabel(app.UIFigure);
            app.VectorEditFieldLabel.Text = 'Vector';
            app.VectorEditField = uieditfield(app.UIFigure, 'text');
            app.VectorEditField.Editable = 'off';
            app.VectorEditField.Tooltip = {'Result from vecotor calculator.'};
            app.ExportSimulationButton = uibutton(app.UIFigure, 'push');
            app.ExportSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @ExportSimulationButtonPushed, true);
            app.ExportSimulationButton.Enable = 'off';
            app.ExportSimulationButton.Text = 'Export Simulation';
            app.SimulationResolutionLabel = uilabel(app.UIFigure);
            app.SimulationResolutionLabel.Text = 'Simulation Resolution';
            app.SimulationResolutionEditField = uieditfield(app.UIFigure, 'numeric');
            app.SimulationResolutionEditField.Limits = [1 Inf];
            app.SimulationResolutionEditField.Tooltip = {'Resolution of simulation.'; 'Increasing will reduce Performance.'};
            app.SimulationResolutionEditField.Value = 2000;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PM20383201

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end